classdef analysis_hrv_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        analysishrvapp           matlab.ui.Figure
        SerialportDropDownLabel  matlab.ui.control.Label
        SerialportDropDown       matlab.ui.control.DropDown
        ConnectButton            matlab.ui.control.Button
        pulsefailureLampLabel    matlab.ui.control.Label
        pulsefailureLamp         matlab.ui.control.Lamp
        pulseOKLampLabel         matlab.ui.control.Label
        pulseOKLamp              matlab.ui.control.Lamp
        BPMGaugeLabel            matlab.ui.control.Label
        BPMGauge                 matlab.ui.control.Gauge
        HistogramButton          matlab.ui.control.Button
        lengthrecordLabel        matlab.ui.control.Label
        lengthrecordSpinner      matlab.ui.control.Spinner
        bpmField                 matlab.ui.control.NumericEditField
        periodField              matlab.ui.control.NumericEditField
        SaveButton               matlab.ui.control.Button
        minperiodesLabel         matlab.ui.control.Label
        minperiodesEditField     matlab.ui.control.NumericEditField
        maxperiodesLabel         matlab.ui.control.Label
        maxperiodesEditField     matlab.ui.control.NumericEditField
        SDNNsEditFieldLabel      matlab.ui.control.Label
        SDNNsEditField           matlab.ui.control.NumericEditField
        MNNsEditFieldLabel       matlab.ui.control.Label
        MNNsEditField            matlab.ui.control.NumericEditField
        signal                   matlab.ui.control.UIAxes
        spectrum                 matlab.ui.control.UIAxes
        Histogram                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       COM;                                                                 %select COM port
       fs=380;                                                              %sampling freq
       BPM;                                                                 %variable BPM
       ppgsignal;                                                           %variable ppgsignal
       time;                                                                %variable time
       freq;
       serial;
    end
    
    methods (Access = public)
                    
                
        function readdata(app)              


           reclength=1:1:app.lengthrecordSpinner.Value;                     %record length
           app.serial=serialport(app.COM,19200);                            %open serial port for comunication with arduino
           app.ConnectButton.BackgroundColor = 'g';
           
           
            for i=1:length(reclength)                                       %read and convert dataPPG string to double format
                data(i)=readline(app.serial);
                app.ppgsignal(i)=str2double(data(i));
                
                app.periodField.Value = i;
            end 
            
           delete(app.serial);
           app.ppgsignal=normalize(app.ppgsignal);                          %normalizing data PPG
           app.ppgsignal=app.ppgsignal(20:length(reclength));               
           
           app.ConnectButton.BackgroundColor = 'w';
           
           
        end 


        function calcfft(app)  
           T=1/app.fs;
           L=length(app.ppgsignal);
           app.time=(0:L-1)*T;
           
           f = app.fs*(0:(L/8))/L;
           
           Y = fft(app.ppgsignal);                                          %calc fft of ppg signal
           P2 = abs(Y/L);
           P1 = P2(1:L/8+1);
           P1(2:end-1) = 2*P1(2:end-1);
           
           [row, col] = find(ismember(P1, max(P1(:))));                     %search max value and position in spectrum
           app.freq=f(row,col)
           app.BPM=f(row,col)*60;                                           %calc BPM from freq
           app.bpmField.Value = app.BPM;                                    %write BPM to text field
                       
           plot(app.signal,app.time,app.ppgsignal,'-r');                    %plot ppg signal
           plot(app.spectrum,f,P1);                                         %plot spectrum of ppg signal
           
           app.BPMGauge.Value=app.BPM;                                      %write BPM to gauge


        end
                
        function calc_histogram(app)
           hold(app.Histogram,'off');
           
           T=1/app.fs;
           L=length(app.ppgsignal);
           app.time=(0:L-1)*T;
           
           for i=1:length(app.ppgsignal)                                   %interral comparator
               if app.ppgsignal(i)>-1.5
                   signal(i)=1;
               else
                   signal(i)=0;
               end
           end
             
           [pks,locs]=findpeaks(signal,app.time);                          %find peaks and location PPG signal
           [row, col] = find(ismember(pks, max(pks(:))));       
                                         
           periode=diff(locs);                                             %calc period between every peaks of PPG signal
           
           app.minperiodesEditField.Value = min(periode);
           app.maxperiodesEditField.Value = max(periode);
           meanperiode = mean(periode,'all');
           app.MNNsEditField.Value =  meanperiode;          
           app.SDNNsEditField.Value = std(periode); 
           histogram(app.Histogram,periode,'BinWidth',0.05);           
           hold(app.Histogram,'on'); 
           xline(app.Histogram,meanperiode,'r');
           
           if max(periode)>(1.5*min(periode))                              %pulse faliture lamp
               
               app.pulsefailureLamp.Color = 'r';
               app.pulseOKLamp.Color = 'w';
           else
               
               app.pulsefailureLamp.Color = 'w';
               app.pulseOKLamp.Color = 'g';
           end
           
           
        end
        
        function savematrix(app)
            myMatrix = app.ppgsignal;

            [file,path,indx] = uiputfile('*.mat')
            filename=append(path,file)
            save(filename,"myMatrix");
        end
        
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialportDropDown.Items = serialportlist("available");    %search available COM ports
            app.pulsefailureLamp.Color = 'w';        
            app.pulseOKLamp.Color = 'w';
        end

        % Callback function: ConnectButton, signal
        function ConnectButtonPushed(app, event)

           readdata(app);
           
           app.ConnectButton.BackgroundColor = 'w';
           calcfft(app);                      
        end

        % Value changed function: SerialportDropDown
        function SerialportDropDownValueChanged(app, event)
            app.COM = app.SerialportDropDown.Value;                         %write select COM port to variable COM            
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)

            readdata(app);
            app.ConnectButton.BackgroundColor = 'w';
            calc_histogram(app);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            savematrix(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.analysishrvapp = uifigure('Visible', 'off');
            app.analysishrvapp.Name = 'Analysis of Heart Rate Variability';
            app.SerialportDropDownLabel = uilabel(app.analysishrvapp);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.analysishrvapp);
            app.SerialportDropDown.Items = {};
            app.SerialportDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialportDropDownValueChanged, true);
            app.SerialportDropDown.Value = {};
            app.ConnectButton = uibutton(app.analysishrvapp, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.pulsefailureLampLabel = uilabel(app.analysishrvapp);
            app.pulsefailureLampLabel.Text = 'pulse failure ';
            app.pulsefailureLamp = uilamp(app.analysishrvapp);
            app.pulseOKLampLabel = uilabel(app.analysishrvapp);
            app.pulseOKLampLabel.Text = 'pulse OK';
            app.pulseOKLamp = uilamp(app.analysishrvapp);
            app.BPMGaugeLabel = uilabel(app.analysishrvapp);
            app.BPMGaugeLabel.Text = 'BPM';
            app.BPMGauge = uigauge(app.analysishrvapp, 'circular');
            app.BPMGauge.Limits = [50 150];
            app.HistogramButton = uibutton(app.analysishrvapp, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.lengthrecordLabel = uilabel(app.analysishrvapp);
            app.lengthrecordLabel.Text = 'length record [ - ]';
            app.lengthrecordSpinner = uispinner(app.analysishrvapp);
            app.lengthrecordSpinner.Step = 200;
            app.lengthrecordSpinner.Limits = [1200 20000];
            app.lengthrecordSpinner.ValueDisplayFormat = '%.0f';
            app.lengthrecordSpinner.Value = 1200;
            app.bpmField = uieditfield(app.analysishrvapp, 'numeric');
            app.bpmField.ValueDisplayFormat = '%.1f';
            app.periodField = uieditfield(app.analysishrvapp, 'numeric');
            app.periodField.ValueDisplayFormat = '%.0f';
            app.SaveButton = uibutton(app.analysishrvapp, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save ';
            app.minperiodesLabel = uilabel(app.analysishrvapp);
            app.minperiodesLabel.Text = 'min. periode [s]';
            app.minperiodesEditField = uieditfield(app.analysishrvapp, 'numeric');
            app.minperiodesEditField.ValueDisplayFormat = '%.3f';
            app.maxperiodesLabel = uilabel(app.analysishrvapp);
            app.maxperiodesLabel.Text = 'max. periode [s]';
            app.maxperiodesEditField = uieditfield(app.analysishrvapp, 'numeric');
            app.maxperiodesEditField.ValueDisplayFormat = '%.3f';
            app.SDNNsEditFieldLabel = uilabel(app.analysishrvapp);
            app.SDNNsEditFieldLabel.Text = 'SDNN [s]';
            app.SDNNsEditField = uieditfield(app.analysishrvapp, 'numeric');
            app.SDNNsEditField.ValueDisplayFormat = '%.3f';
            app.MNNsEditFieldLabel = uilabel(app.analysishrvapp);
            app.MNNsEditFieldLabel.Text = 'MNN [s]';
            app.MNNsEditField = uieditfield(app.analysishrvapp, 'numeric');
            app.MNNsEditField.ValueDisplayFormat = '%.3f';
            app.signal = uiaxes(app.analysishrvapp);
            title(app.signal, 'PPG signal')
            xlabel(app.signal, 't [s]')
            ylabel(app.signal, 'PPG [-]')
            app.signal.ButtonDownFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.spectrum = uiaxes(app.analysishrvapp);
            title(app.spectrum, 'Single-Sided Amplitude Spectrum of PPG(f)')
            xlabel(app.spectrum, 'f [Hz]')
            ylabel(app.spectrum, '|PPG(f)|')
            app.Histogram = uiaxes(app.analysishrvapp);
            title(app.Histogram, 'Histogram Distribution Periods ')
            xlabel(app.Histogram, 'periode [s]')
            ylabel(app.Histogram, 'n-periode [number]')
            % Show the figure after all components are created
            app.analysishrvapp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_hrv_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.analysishrvapp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.analysishrvapp)
        end
    end
end