classdef AnalysisofHRV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisofHeartRateVariabilityUIFigure  matlab.ui.Figure
        SerialportDropDownLabel  matlab.ui.control.Label
        SerialportDropDown       matlab.ui.control.DropDown
        ConnectButton            matlab.ui.control.Button
        pulsefailureLampLabel    matlab.ui.control.Label
        pulsefailureLamp         matlab.ui.control.Lamp
        pulseOKLampLabel         matlab.ui.control.Label
        pulseOKLamp              matlab.ui.control.Lamp
        BPMGaugeLabel            matlab.ui.control.Label
        BPMGauge                 matlab.ui.control.Gauge
        HistogramButton          matlab.ui.control.Button
        lengthrecordLabel        matlab.ui.control.Label
        lengthrecordSpinner      matlab.ui.control.Spinner
        bpmField                 matlab.ui.control.NumericEditField
        periodField              matlab.ui.control.NumericEditField
        SaveButton               matlab.ui.control.Button
        minperiodesLabel         matlab.ui.control.Label
        minperiodesEditField     matlab.ui.control.NumericEditField
        maxperiodesLabel         matlab.ui.control.Label
        maxperiodesEditField     matlab.ui.control.NumericEditField
        SDNNsEditFieldLabel      matlab.ui.control.Label
        SDNNsEditField           matlab.ui.control.NumericEditField
        MNNsEditFieldLabel       matlab.ui.control.Label
        MNNsEditField            matlab.ui.control.NumericEditField
        signal                   matlab.ui.control.UIAxes
        spectrum                 matlab.ui.control.UIAxes
        Histogram                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       COM;                                                                 %select COM port
       fs=380;                                                              %sampling freq
       BPM;                                                                 %variable BPM
       ppgsignal;                                                           %variable ppgsignal
       time;                                                                %variable time
       freq;
       serial;
    end
    
    methods (Access = public)
                    
                
        function readdata(app)              


           reclength=1:1:app.lengthrecordSpinner.Value;                     %record length
           app.serial=serialport(app.COM,19200);                            %open serial port for comunication with arduino
           app.ConnectButton.BackgroundColor = 'g';
           
           
            for i=1:length(reclength)                                       %read and convert dataPPG string to double format
                data(i)=readline(app.serial);
                app.ppgsignal(i)=str2double(data(i));
                
                app.periodField.Value = i;
            end 
            
           delete(app.serial);
           app.ppgsignal=normalize(app.ppgsignal);                                    %normalizing data PPG
           app.ppgsignal=app.ppgsignal(20:length(reclength));               %
           
           app.ConnectButton.BackgroundColor = 'w';
           
           
        end 


        function calcfft(app)  
           T=1/app.fs;
           L=length(app.ppgsignal);
           app.time=(0:L-1)*T;
           
           f = app.fs*(0:(L/8))/L;
           
           Y = fft(app.ppgsignal);                                          %calc fft of ppg signal
           P2 = abs(Y/L);
           P1 = P2(1:L/8+1);
           P1(2:end-1) = 2*P1(2:end-1);
           
           [row, col] = find(ismember(P1, max(P1(:))));                     %search max value and position in spectrum
           app.freq=f(row,col)
           app.BPM=f(row,col)*60;                                           %calc BPM from freq
           app.bpmField.Value = app.BPM;                                    %write BPM to text field
                       
           plot(app.signal,app.time,app.ppgsignal,'-r');                    %plot ppg signal
           plot(app.spectrum,f,P1);                                         %plot spectrum of ppg signal
           
           app.BPMGauge.Value=app.BPM;                                      %write BPM to gauge


        end
                
        function calc_histogram(app)
           hold(app.Histogram,'off');
           
           T=1/app.fs;
           L=length(app.ppgsignal);
           app.time=(0:L-1)*T;
           
           for i=1:length(app.ppgsignal)
               if app.ppgsignal(i)>-1.5
                   signal(i)=1;
               else
                   signal(i)=0;
               end
           end
             
           [pks,locs]=findpeaks(signal,app.time);                    %find peaks and location PPG signal
           [row, col] = find(ismember(pks, max(pks(:))));

           locs
           pks
                                 
%            for n=1:(length(locs)-1)                                         %calc period between every peaks of PPG signal
%                periode(n)=locs(n+1)-locs(n);                              
%            end        
               
           periode=diff(locs)
           
%            periode=[1.1263,    1.1053,    1.1105,    1.1026,    1.0632,    1.0368,    1.0474,    1.0447,    1.0526,    1.0553,    1.0658, 1.3];         
         
           app.minperiodesEditField.Value = min(periode);
           app.maxperiodesEditField.Value = max(periode);
           meanperiode = mean(periode,'all');
           app.MNNsEditField.Value =  meanperiode;          
           app.SDNNsEditField.Value = std(periode); 
           histogram(app.Histogram,periode,'BinWidth',0.05);           
           hold(app.Histogram,'on'); 
           xline(app.Histogram,meanperiode,'r');
           
           if max(periode)>(1.2*min(periode))
               
               app.pulsefailureLamp.Color = 'r';
               app.pulseOKLamp.Color = 'w';
           else
               
               app.pulsefailureLamp.Color = 'w';
               app.pulseOKLamp.Color = 'g';
           end
           
           
        end
        
        function savematrix(app)
            myMatrix = app.ppgsignal;

            [file,path,indx] = uiputfile('*.mat')
            filename=append(path,file)
            save(filename,"myMatrix");
        end
        
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialportDropDown.Items = serialportlist("available");        %search available COM ports
            app.pulsefailureLamp.Color = 'w';        
            app.pulseOKLamp.Color = 'w';
        end

        % Callback function: ConnectButton, signal
        function ConnectButtonPushed(app, event)
%            app.ConnectButton.BackgroundColor = 'g';
           readdata(app);
           
           app.ConnectButton.BackgroundColor = 'w';
           calcfft(app);                      
        end

        % Value changed function: SerialportDropDown
        function SerialportDropDownValueChanged(app, event)
            app.COM = app.SerialportDropDown.Value;                         %write select COM port to variable COM            
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
%             app.ConnectButton.BackgroundColor = 'g';
            readdata(app);
            app.ConnectButton.BackgroundColor = 'w';
            calc_histogram(app);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            savematrix(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisofHeartRateVariabilityUIFigure = uifigure('Visible', 'off');
            app.AnalysisofHeartRateVariabilityUIFigure.Name = 'Analysis of Heart Rate Variability';
            app.SerialportDropDownLabel = uilabel(app.AnalysisofHeartRateVariabilityUIFigure);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.AnalysisofHeartRateVariabilityUIFigure);
            app.SerialportDropDown.Items = {};
            app.SerialportDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialportDropDownValueChanged, true);
            app.SerialportDropDown.Value = {};
            app.ConnectButton = uibutton(app.AnalysisofHeartRateVariabilityUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.pulsefailureLampLabel = uilabel(app.AnalysisofHeartRateVariabilityUIFigure);
            app.pulsefailureLampLabel.Text = 'pulse failure ';
            app.pulsefailureLamp = uilamp(app.AnalysisofHeartRateVariabilityUIFigure);
            app.pulseOKLampLabel = uilabel(app.AnalysisofHeartRateVariabilityUIFigure);
            app.pulseOKLampLabel.Text = 'pulse OK';
            app.pulseOKLamp = uilamp(app.AnalysisofHeartRateVariabilityUIFigure);
            app.BPMGaugeLabel = uilabel(app.AnalysisofHeartRateVariabilityUIFigure);
            app.BPMGaugeLabel.Text = 'BPM';
            app.BPMGauge = uigauge(app.AnalysisofHeartRateVariabilityUIFigure, 'circular');
            app.BPMGauge.Limits = [50 150];
            app.HistogramButton = uibutton(app.AnalysisofHeartRateVariabilityUIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.lengthrecordLabel = uilabel(app.AnalysisofHeartRateVariabilityUIFigure);
            app.lengthrecordLabel.Text = 'length record [ - ]';
            app.lengthrecordSpinner = uispinner(app.AnalysisofHeartRateVariabilityUIFigure);
            app.lengthrecordSpinner.Step = 200;
            app.lengthrecordSpinner.Limits = [1200 20000];
            app.lengthrecordSpinner.ValueDisplayFormat = '%.0f';
            app.lengthrecordSpinner.Value = 1200;
            app.bpmField = uieditfield(app.AnalysisofHeartRateVariabilityUIFigure, 'numeric');
            app.bpmField.ValueDisplayFormat = '%.1f';
            app.periodField = uieditfield(app.AnalysisofHeartRateVariabilityUIFigure, 'numeric');
            app.periodField.ValueDisplayFormat = '%.0f';
            app.SaveButton = uibutton(app.AnalysisofHeartRateVariabilityUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save ';
            app.minperiodesLabel = uilabel(app.AnalysisofHeartRateVariabilityUIFigure);
            app.minperiodesLabel.Text = 'min. periode [s]';
            app.minperiodesEditField = uieditfield(app.AnalysisofHeartRateVariabilityUIFigure, 'numeric');
            app.minperiodesEditField.ValueDisplayFormat = '%.3f';
            app.maxperiodesLabel = uilabel(app.AnalysisofHeartRateVariabilityUIFigure);
            app.maxperiodesLabel.Text = 'max. periode [s]';
            app.maxperiodesEditField = uieditfield(app.AnalysisofHeartRateVariabilityUIFigure, 'numeric');
            app.maxperiodesEditField.ValueDisplayFormat = '%.3f';
            app.SDNNsEditFieldLabel = uilabel(app.AnalysisofHeartRateVariabilityUIFigure);
            app.SDNNsEditFieldLabel.Text = 'SDNN [s]';
            app.SDNNsEditField = uieditfield(app.AnalysisofHeartRateVariabilityUIFigure, 'numeric');
            app.SDNNsEditField.ValueDisplayFormat = '%.3f';
            app.MNNsEditFieldLabel = uilabel(app.AnalysisofHeartRateVariabilityUIFigure);
            app.MNNsEditFieldLabel.Text = 'MNN [s]';
            app.MNNsEditField = uieditfield(app.AnalysisofHeartRateVariabilityUIFigure, 'numeric');
            app.MNNsEditField.ValueDisplayFormat = '%.3f';
            app.signal = uiaxes(app.AnalysisofHeartRateVariabilityUIFigure);
            title(app.signal, 'PPG signal')
            xlabel(app.signal, 't [s]')
            ylabel(app.signal, 'PPG [-]')
            app.signal.ButtonDownFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.spectrum = uiaxes(app.AnalysisofHeartRateVariabilityUIFigure);
            title(app.spectrum, 'Single-Sided Amplitude Spectrum of PPG(f)')
            xlabel(app.spectrum, 'f [Hz]')
            ylabel(app.spectrum, '|PPG(f)|')
            app.Histogram = uiaxes(app.AnalysisofHeartRateVariabilityUIFigure);
            title(app.Histogram, 'Histogram Distribution Periods ')
            xlabel(app.Histogram, 'periode [s]')
            ylabel(app.Histogram, 'n-periode [number]')
            % Show the figure after all components are created
            app.AnalysisofHeartRateVariabilityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisofHRV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalysisofHeartRateVariabilityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisofHeartRateVariabilityUIFigure)
        end
    end
end