classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SerialportDropDownLabel  matlab.ui.control.Label
        SerialportDropDown       matlab.ui.control.DropDown
        ConnectButton            matlab.ui.control.Button
        pulsefailureLampLabel    matlab.ui.control.Label
        pulsefailureLamp         matlab.ui.control.Lamp
        pulseOKLampLabel         matlab.ui.control.Label
        pulseOKLamp              matlab.ui.control.Lamp
        BPMGaugeLabel            matlab.ui.control.Label
        BPMGauge                 matlab.ui.control.Gauge
        HistogramButton          matlab.ui.control.Button
        signal                   matlab.ui.control.UIAxes
        spectrum                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       COM;
       fs=190;
       BPM;
       ppgsignal;
    end
    
    methods (Access = public)
                    
                
        function readdata(app)
           app.ConnectButton.BackgroundColor = 'g';
           app.pulseOKLamp.Color = 'w'; 
           reclength=1:1:600; %record length
           serial=serialport(app.COM,9600); %open serial port for comunication with arduino


            for i=1:length(reclength)
                data(i)=readline(serial);
                app.ppgsignal(i)=str2double(data(i));
            end 

           app.ppgsignal=normalize(app.ppgsignal);
           app.ppgsignal=app.ppgsignal(20:length(reclength));
                      
           
           T=1/app.fs;
           L=length(app.ppgsignal);
           time=(0:L-1)*T;
           
           f = app.fs*(0:(L/8))/L;
           
           Y = fft(app.ppgsignal);
           P2 = abs(Y/L);
           P1 = P2(1:L/8+1);
           P1(2:end-1) = 2*P1(2:end-1);
           
           [row, col] = find(ismember(P1, max(P1(:))));
           freq=f(row,col)
           app.BPM=f(row,col)*60
           
                       
           plot(app.signal,time,app.ppgsignal,'-r');
           plot(app.spectrum,f,P1); 
           
           app.BPMGauge.Value=app.BPM;
           if app.BPM > 60
           app.pulseOKLamp.Color = 'g';
           end
        end
        
        function histogram(app)
           histogram(app.UIAxes,app.ppgsignal)
        end
        
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialportDropDown.Items=serialportlist("available");
            app.pulseOKLamp.Color = 'w';
        end

        % Callback function: ConnectButton, signal
        function ConnectButtonPushed(app, event)
           readdata(app);
           app.ConnectButton.BackgroundColor = 'w';
           
        end

        % Value changed function: SerialportDropDown
        function SerialportDropDownValueChanged(app, event)
            app.COM = app.SerialportDropDown.Value;
            
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            histogram(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SerialportDropDownLabel = uilabel(app.UIFigure);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.UIFigure);
            app.SerialportDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialportDropDownValueChanged, true);
            app.SerialportDropDown.Value = 'Option 2';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.pulsefailureLampLabel = uilabel(app.UIFigure);
            app.pulsefailureLampLabel.Text = 'pulse failure ';
            app.pulsefailureLamp = uilamp(app.UIFigure);
            app.pulseOKLampLabel = uilabel(app.UIFigure);
            app.pulseOKLampLabel.Text = 'pulse OK';
            app.pulseOKLamp = uilamp(app.UIFigure);
            app.BPMGaugeLabel = uilabel(app.UIFigure);
            app.BPMGaugeLabel.Text = 'BPM';
            app.BPMGauge = uigauge(app.UIFigure, 'circular');
            app.BPMGauge.Limits = [50 150];
            app.HistogramButton = uibutton(app.UIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.signal = uiaxes(app.UIFigure);
            title(app.signal, 'PPG signal')
            xlabel(app.signal, 't [s]')
            ylabel(app.signal, 'PPG (-)')
            app.signal.ButtonDownFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.spectrum = uiaxes(app.UIFigure);
            title(app.spectrum, 'Single-Sided Amplitude Spectrum of PPG(f)')
            xlabel(app.spectrum, 'f (Hz)')
            ylabel(app.spectrum, '|PPG(f)|')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end